classdef apptest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeakerVerification   matlab.ui.Figure                   % Speaker ...
        mainPanel             matlab.ui.container.Panel          % Single m...
        singleMicButton       matlab.ui.control.Button           % Single m...
        LabelNumericEditField matlab.ui.control.Label            % Number o...
        testMicNumText        matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                 matlab.ui.control.Label            % Euclidea...
        testMicEucDiffText    matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField        matlab.ui.control.Label            % Nickname:
        nicknameText          matlab.ui.control.EditField       
        Label3                matlab.ui.control.Label            % Gender:
        genderText            matlab.ui.control.EditField       
        micLamp               matlab.ui.control.Lamp            
        Label4                matlab.ui.control.Label            % Auxiliary
        auxInfoText           matlab.ui.control.TextArea        
        Panel                 matlab.ui.container.Panel          % Adding n...
        Button                matlab.ui.control.Button           % Add new ...
        LabelListBox          matlab.ui.control.Label            % Gender:
        newGenderList         matlab.ui.control.ListBox          % male, fe...
        Label2                matlab.ui.control.Label            % Nickname:
        newNameText           matlab.ui.control.EditField       
        LabelTextArea         matlab.ui.control.Label            % Auxiliary
        newAuxInfoText        matlab.ui.control.TextArea        
        NewDataLamp           matlab.ui.control.Lamp            
    end


    properties (Access = private)
        code % codebook
        codeBookBaseSize 
        fs = 12500
    end

    methods (Access = private)
        
        function goodstr = getDataStrFromTxtCell(app,txtdata,strpattern)
               i = strfind(txtdata,strpattern);
               i = find(~cellfun(@isempty,i));
               goodstr = strrep(txtdata{i},strpattern,'');
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = dir('train\*.wav');
            app.codeBookBaseSize = length(d);
            app.code = train('train\',app.codeBookBaseSize);
        end

        % singleMicButton button pushed function
        function uitestmic(app)
            app.micLamp.Enable = 'on';
            [testMicNumDetected, testMicDiffDetected] = testmic(app.code);
            app.micLamp.Enable = 'off';
            app.testMicNumText.Value = testMicNumDetected;
            app.testMicEucDiffText.Value = testMicDiffDetected;
            txtstrdir = sprintf('train/s%d.txt',testMicNumDetected);
            txtdir = dir(txtstrdir);
            if length(txtdir) == 1
               txtdata = importdata(txtstrdir);
               
               app.nicknameText.Value = app.getDataStrFromTxtCell(txtdata,'nickname: ');
               app.genderText.Value = app.getDataStrFromTxtCell(txtdata,'gender: ');
               
               txtstrdir = sprintf('train/s%d.wav',testMicNumDetected);
               txtdir = dir(txtstrdir);
               auxstr = sprintf('%s, %s',app.getDataStrFromTxtCell(txtdata,'auxinfo: '), txtdir.date);
               app.auxInfoText.Value = auxstr;
            else
                
            end
        end

        % Button button pushed function
        function addNewTrainingInfo(app)
            app.NewDataLamp.Enable = 'on';
            recObj = audiorecorder(app.fs,16,1);
            disp('Start speaking.');
            recordblocking(recObj, 2);
            disp('End of Recording.');
            app.NewDataLamp.Enable = 'off';
            y = getaudiodata(recObj);
            app.codeBookBaseSize = app.codeBookBaseSize +1;
            wavfilepath = sprintf('train\\s%d.wav',app.codeBookBaseSize);
            audiowrite(wavfilepath,y,app.fs);
            txtfilepath = sprintf('train\\s%d.txt',app.codeBookBaseSize);
            txtid = fopen(txtfilepath,'wt');
            
            name = app.newNameText.Value;
            gender = app.newGenderList.Value;
            auxinfo = app.newAuxInfoText.Value;
            
            fprintf(txtid,'nickname: %s\ngender: %s\nauxinfo: %s',name,gender,auxinfo{1});
            
            fclose(txtid);
            
            app.code = train('train\',app.codeBookBaseSize);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpeakerVerification = uifigure;
            app.SpeakerVerification.Name = 'Speaker Verification - pm 2017';
            setAutoResize(app, app.SpeakerVerification, true)
            app.mainPanel = uipanel(app.SpeakerVerification);
            app.mainPanel.Title = 'Single microphone test section.';
            app.mainPanel.Units = 'pixels';
            app.singleMicButton = uibutton(app.mainPanel, 'push');
            app.singleMicButton.ButtonPushedFcn = createCallbackFcn(app, @uitestmic);
            app.singleMicButton.Text = 'Single microphone test';
            app.LabelNumericEditField = uilabel(app.mainPanel);
            app.LabelNumericEditField.Text = 'Number of detected speaker:';
            app.testMicNumText = uieditfield(app.mainPanel, 'numeric');
            app.testMicNumText.Editable = 'off';
            app.Label = uilabel(app.mainPanel);
            app.Label.Text = 'Euclidean distance:';
            app.testMicEucDiffText = uieditfield(app.mainPanel, 'numeric');
            app.testMicEucDiffText.Editable = 'off';
            app.LabelEditField = uilabel(app.mainPanel);
            app.LabelEditField.Text = 'Nickname:';
            app.nicknameText = uieditfield(app.mainPanel, 'text');
            app.nicknameText.Editable = 'off';
            app.Label3 = uilabel(app.mainPanel);
            app.Label3.Text = 'Gender:';
            app.genderText = uieditfield(app.mainPanel, 'text');
            app.genderText.Editable = 'off';
            app.micLamp = uilamp(app.mainPanel);
            app.micLamp.Enable = 'off';
            app.Label4 = uilabel(app.mainPanel);
            app.Label4.Text = {'Auxiliary'; ' info:'};
            app.auxInfoText = uitextarea(app.mainPanel);
            app.auxInfoText.Editable = 'off';
            app.Panel = uipanel(app.SpeakerVerification);
            app.Panel.Title = 'Adding new training info section.';
            app.Panel.Units = 'pixels';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @addNewTrainingInfo);
            app.Button.Text = 'Add new training info';
            app.LabelListBox = uilabel(app.Panel);
            app.LabelListBox.Text = {'Gender:'; '(there is only two)'};
            app.newGenderList = uilistbox(app.Panel);
            app.newGenderList.Items = {'male', 'female'};
            app.newGenderList.Value = 'male';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Nickname:';
            app.newNameText = uieditfield(app.Panel, 'text');
            app.LabelTextArea = uilabel(app.Panel);
            app.LabelTextArea.Text = {'Auxiliary'; ' info:'};
            app.newAuxInfoText = uitextarea(app.Panel);
            app.NewDataLamp = uilamp(app.Panel);
            app.NewDataLamp.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = apptest()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeakerVerification)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeakerVerification)
        end
    end
end